classdef RAPID_Demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RAPIDCalibrationToolUIFigure  matlab.ui.Figure
        RefMicCal                     matlab.ui.container.Panel
        RefMicCalList                 matlab.ui.control.ListBox
        RefMicCalImage                matlab.ui.control.Image
        RefMicCalAxes                 matlab.ui.control.UIAxes
        RefMic                        matlab.ui.container.Panel
        RefMicList                    matlab.ui.control.ListBox
        RefMicImage                   matlab.ui.control.Image
        RefMicAxes                    matlab.ui.control.UIAxes
        TargetMic                     matlab.ui.container.Panel
        TargetMicList                 matlab.ui.control.ListBox
        TargetMicImage                matlab.ui.control.Image
        TargetMicAxes                 matlab.ui.control.UIAxes
        RefRAPID                      matlab.ui.container.Panel
        RefRAPIDList                  matlab.ui.control.ListBox
        RefRAPIDLoad                  matlab.ui.control.Button
        RefRAPIDCapture               matlab.ui.control.Button
        RefRAPIDClear                 matlab.ui.control.Button
        RefRAPIDAxes                  matlab.ui.control.UIAxes
        Save                          matlab.ui.container.Panel
        SaveName                      matlab.ui.control.EditField
        SaveBrowse                    matlab.ui.control.Button
        SaveSave                      matlab.ui.control.Button
        SaveSplit                     matlab.ui.control.CheckBox
        TargetRAPID                   matlab.ui.container.Panel
        TargetRAPIDImage              matlab.ui.control.Image
        TargetRAPIDList               matlab.ui.control.ListBox
        TargetRAPIDLoad               matlab.ui.control.Button
        TargetRAPIDCapture            matlab.ui.control.Button
        TargetRAPIDClear              matlab.ui.control.Button
        Mic112CheckBox                matlab.ui.control.CheckBox
        Mic1324CheckBox               matlab.ui.control.CheckBox
        Mic2536CheckBox               matlab.ui.control.CheckBox
        Mic3648CheckBox               matlab.ui.control.CheckBox
        Options                       matlab.ui.container.Panel
        BandsDropDown                 matlab.ui.control.DropDown
        BandsDropDown_2               matlab.ui.control.DropDown
        BandsDropDown_3               matlab.ui.control.DropDown
        BandsDropDown_4               matlab.ui.control.DropDown
        BandsDropDown_5               matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TargetRAPIDList.Value = {'Capture 01','Capture 02','Capture 04' };               
            app.RefRAPIDList.Value = {'Ref Capture 01','Ref Capture 02'};               
            app.TargetRAPIDImage.ImageSource = "Eigenmike.png";
            app.RefMicImage.ImageSource = "Fig21_POLAR_DBX_Flame.png";
            app.TargetMicImage.ImageSource = "Fig22_POLAR_EIG_Flame.png";
            app.RefMicCalImage.ImageSource = "DBX_RTA_M.jpg";

            load TargetRAPID.mat H_rapid Bands
            H_rapid = H_rapid(:,:,1:3);
            M=12+(1:12);
            for (m=1:length(M))
                r = 3-floor((m-1)/3);
                c = mod(m-1,3);
                a = axes(app.TargetRAPID,'position',[.06+.31*c .04+.18*r .30 .17]);
                semilogx(a,Bands,squeeze(H_rapid(:,M(m),:)-median(H_rapid(:,:,:),2))); hold(a,'on');
                grid(a,'on');
                axis(a,[100 20000 -1.5 1.5]);
                set(a,'fontsize',10);
                if (r>0) set(a,'xticklabel',[]); end;
                if (r==0) set(a,'xtick',[1000 10000]); set(a,'xticklabel',{'1kHz', '10kHz'}); end;
                if (c>0) set(a,'yticklabel',[]); end;
                if (c==0) set(a,'yticklabel',[-1 0 1]); set(a,'yticklabel',{'-1dB','0dB','1dB'}); end;
                text(a,4000,1.2,sprintf('Mic %d',M(m)));
            end; 


            load('DBX.cal');
            DBX     = spline(DBX(:,1),DBX(:,2),Bands)';
            semilogx(app.RefMicCalAxes,Bands,DBX,'linewidth',2);
            axis(app.RefMicCalAxes,[100 20000 -5 5]);
            grid(app.RefMicCalAxes,'on');
            xlabel(app.RefMicCalAxes,[]);
            ylabel(app.RefMicCalAxes,[]);
            title(app.RefMicCalAxes,[]);
            set(app.RefMicCalAxes,'XTickLabel',[],'YTickLabel',[]);
            
            load DBX_Offaxis.mat DBX_Hpow
            E = [ 0:10:180 ]';
            DBX_Hdiff = 10*log10(sum(sum(DBX_Hpow(:,:,1:end-1) .* permute(sin(E/180*pi),[2 1 3]),2),3)+.0000001);
            DBX_Hdiff = DBX_Hdiff - 10*log10(sum(sum(repmat(DBX_Hpow(:,1,1:end-1),1,length(E),1) .* permute(sin(E/180*pi),[2 1 3]),2),3)+.0000001);
            semilogx(app.RefMicAxes,Bands,DBX_Hdiff,'linewidth',2);
            axis(app.RefMicAxes,[100 20000 -5 5]);
            grid(app.RefMicAxes,'on');
            xlabel(app.RefMicAxes,[]);
            ylabel(app.RefMicAxes,[]);
            title(app.RefMicAxes,[]);
            set(app.RefMicAxes,'XTickLabel',[],'YTickLabel',[]);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RAPIDCalibrationToolUIFigure = uifigure('Visible', 'off');
            app.RAPIDCalibrationToolUIFigure.Name = 'RAPID Calibration Tool';
            app.RefMicCal = uipanel(app.RAPIDCalibrationToolUIFigure);
            app.RefMicCal.Title = 'Reference Microphone On-Axis Calibration';
            app.RefMicCalList = uilistbox(app.RefMicCal);
            app.RefMicCalList.Items = {'FLAT', 'DBX RTA-M', 'miniDSP UMIK-1', 'Behringer ECM8000', 'CUSTOM'};
            app.RefMicCalList.Value = 'DBX RTA-M';
            app.RefMicCalImage = uiimage(app.RefMicCal);
            app.RefMicCalAxes = uiaxes(app.RefMicCal);
            title(app.RefMicCalAxes, 'Title')
            xlabel(app.RefMicCalAxes, 'X')
            ylabel(app.RefMicCalAxes, 'Y')
            app.RefMic = uipanel(app.RAPIDCalibrationToolUIFigure);
            app.RefMic.Title = 'Reference Microphone Diffuse Correction';
            app.RefMicList = uilistbox(app.RefMic);
            app.RefMicList.Items = {'OMNI', '1/4" 6mm Probe', '1/2" 12mm Probe', 'DBX RTA-M', 'miniDSP UMIK-1', 'Behringer ECM8000', 'CUSTOM'};
            app.RefMicList.Value = 'DBX RTA-M';
            app.RefMicImage = uiimage(app.RefMic);
            app.RefMicAxes = uiaxes(app.RefMic);
            title(app.RefMicAxes, 'Title')
            xlabel(app.RefMicAxes, 'X')
            ylabel(app.RefMicAxes, 'Y')
            app.TargetMic = uipanel(app.RAPIDCalibrationToolUIFigure);
            app.TargetMic.Title = 'Target Microphone';
            app.TargetMicList = uilistbox(app.TargetMic);
            app.TargetMicList.Items = {'FLAT', 'Eigenmike', 'DAMKSP16', 'Zylia ZM-1', ''};
            app.TargetMicList.Value = 'Eigenmike';
            app.TargetMicImage = uiimage(app.TargetMic);
            app.TargetMicAxes = uiaxes(app.TargetMic);
            title(app.TargetMicAxes, 'Title')
            xlabel(app.TargetMicAxes, 'X')
            ylabel(app.TargetMicAxes, 'Y')
            app.RefRAPID = uipanel(app.RAPIDCalibrationToolUIFigure);
            app.RefRAPID.Title = 'Reference Microphone RAPID';
            app.RefRAPIDList = uilistbox(app.RefRAPID);
            app.RefRAPIDList.Items = {'Ref Capture 01', 'Ref Capture 02', 'Ref Capture 03', ''};
            app.RefRAPIDList.Multiselect = 'on';
            app.RefRAPIDList.Value = {'Ref Capture 01'};
            app.RefRAPIDLoad = uibutton(app.RefRAPID, 'push');
            app.RefRAPIDLoad.Text = 'Load';
            app.RefRAPIDCapture = uibutton(app.RefRAPID, 'push');
            app.RefRAPIDCapture.Enable = 'off';
            app.RefRAPIDCapture.Text = 'Capture';
            app.RefRAPIDClear = uibutton(app.RefRAPID, 'push');
            app.RefRAPIDClear.Text = 'Clear';
            app.RefRAPIDAxes = uiaxes(app.RefRAPID);
            title(app.RefRAPIDAxes, 'Title')
            xlabel(app.RefRAPIDAxes, 'X')
            ylabel(app.RefRAPIDAxes, 'Y')
            app.Save = uipanel(app.RAPIDCalibrationToolUIFigure);
            app.Save.Title = 'Create RAPID Calibration';
            app.SaveName = uieditfield(app.Save, 'text');
            app.SaveName.Value = '20200901_Calibration';
            app.SaveBrowse = uibutton(app.Save, 'push');
            app.SaveBrowse.Text = 'Browse';
            app.SaveSave = uibutton(app.Save, 'push');
            app.SaveSave.Text = 'SAVE FILTERS';
            app.SaveSplit = uicheckbox(app.Save);
            app.SaveSplit.Text = 'Separate Bulk EQ';
            app.TargetRAPID = uipanel(app.RAPIDCalibrationToolUIFigure);
            app.TargetRAPID.Title = 'Target Microphone RAPID';
            app.TargetRAPIDImage = uiimage(app.TargetRAPID);
            app.TargetRAPIDList = uilistbox(app.TargetRAPID);
            app.TargetRAPIDList.Items = {'Capture 01', 'Capture 02', 'Capture 03', 'Capture 04', 'Capture 05'};
            app.TargetRAPIDList.Multiselect = 'on';
            app.TargetRAPIDList.Value = {'Capture 03'};
            app.TargetRAPIDLoad = uibutton(app.TargetRAPID, 'push');
            app.TargetRAPIDLoad.Text = 'Load';
            app.TargetRAPIDCapture = uibutton(app.TargetRAPID, 'push');
            app.TargetRAPIDCapture.Enable = 'off';
            app.TargetRAPIDCapture.Text = 'Capture';
            app.TargetRAPIDClear = uibutton(app.TargetRAPID, 'push');
            app.TargetRAPIDClear.Text = 'Clear';
            app.Mic112CheckBox = uicheckbox(app.TargetRAPID);
            app.Mic112CheckBox.Text = 'Mic   1-12';
            app.Mic1324CheckBox = uicheckbox(app.TargetRAPID);
            app.Mic1324CheckBox.Text = 'Mic  13-24';
            app.Mic1324CheckBox.Value = true;
            app.Mic2536CheckBox = uicheckbox(app.TargetRAPID);
            app.Mic2536CheckBox.Text = 'Mic  25-36';
            app.Mic3648CheckBox = uicheckbox(app.TargetRAPID);
            app.Mic3648CheckBox.Text = 'Mic  36-48';
            app.Options = uipanel(app.RAPIDCalibrationToolUIFigure);
            app.Options.Title = 'Options';
            app.BandsDropDown = uidropdown(app.Options);
            app.BandsDropDown.Items = {'10 Bands', '25 Bands', '30 Bands', '40 bands', '50 Bands'};
            app.BandsDropDown.Value = '25 Bands';
            app.BandsDropDown_2 = uidropdown(app.Options);
            app.BandsDropDown_2.Items = {'100Hz - 16kHz', '100Hz - 20kHz', '100Hz - 24kHz', '  50Hz - 16kHz', '  50Hz - 24kHz'};
            app.BandsDropDown_2.Value = '100Hz - 20kHz';
            app.BandsDropDown_3 = uidropdown(app.Options);
            app.BandsDropDown_3.Items = {'48000Hz Sample Rate', '44100Hz Sample Rate ', '32000Hz Sample Rate'};
            app.BandsDropDown_3.Value = '48000Hz Sample Rate';
            app.BandsDropDown_4 = uidropdown(app.Options);
            app.BandsDropDown_4.Items = {'Medium Smoothing'};
            app.BandsDropDown_4.Value = 'Medium Smoothing';
            app.BandsDropDown_5 = uidropdown(app.Options);
            app.BandsDropDown_5.Items = {'    5ms Filter Length', ' 10ms Filter Length', '  20ms Filter Length', '  50ms Filter Length', '100ms Filter Length'};
            app.BandsDropDown_5.Value = '  20ms Filter Length';
            % Show the figure after all components are created
            app.RAPIDCalibrationToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RAPID_Demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RAPIDCalibrationToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RAPIDCalibrationToolUIFigure)
        end
    end
end